classdef LogLogApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ExportButton         matlab.ui.control.Button
        InvertABSSwitch      matlab.ui.control.ToggleSwitch
        InvertABSLabel       matlab.ui.control.Label
        FitEqnTextArea       matlab.ui.control.TextArea
        FitEqnTextAreaLabel  matlab.ui.control.Label
        WavelengthText       matlab.ui.control.Label
        xLabel               matlab.ui.control.Label
        MaxX                 matlab.ui.control.NumericEditField
        xLabel_2             matlab.ui.control.Label
        MinX                 matlab.ui.control.NumericEditField
        PrevButton           matlab.ui.control.Button
        NextButton           matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Store main app inside object CallingApp
        Index % Store Index from SpectraBuilderApp
    end
    
    methods (Access = private)
        function [fitresult, gof] = createFit(app)
            global xData yData
            global KinInd LogLogTime LogLogABS LogData fstring
            global xPoints yfitData fitresult %#ok<REDEFGI>

            %% Fit: 'Log-Log Graph' w/ check for inverse fit data
            [xData, yData] = prepareCurveData(LogLogTime, LogLogABS);
            
            % Set up fittype and options.
            ft = fittype( 'poly1' );
            excludedPoints = (xData < xPoints(1,1)) | (xData > xPoints(1,2));
            opts = fitoptions( 'Method', 'LinearLeastSquares' );
            opts.Robust = 'Bisquare';
            opts.Exclude = excludedPoints;

            % Fit model to data.
            [fitresult, gof] = fit( xData, yData, ft, opts );
            yfitData = feval(fitresult, xData);
            fchar = evalc('fitresult');
            fstring = convertCharsToStrings(fchar);
            app.FitEqnTextArea.Value = fstring;

            % To retrieve proper wavelengths of observed data
            wavelengths = LogData.Properties.VariableNames(2:end);
            temp = cell2mat(wavelengths);
            temp = split(temp,'x');
            plot_wavelengths = sort(str2double(temp));
            app.WavelengthText.Text = "@" + plot_wavelengths(KinInd-1) + "nm";
            save_to_base(1);
        end

        function shader(app, ~, ~)
            global xPoints CenterTime X1 X2
            global xPointsROI Shade ShadeArea
            global xMin xMax LogLogABS


            % If called without callback execute default
            if nargin == 1
                Shade = [];
                ShadeArea = [];
                xPoints = [];

                CenterTime = mean(LogLogABS);
                ShadeArea{1,1} = [CenterTime-2 CenterTime+1];

                %% First time setup of plotting interactible area
                if isempty(app.UIAxes) == 0
                    
                    % Clear any existing plots
                    cla(app.UIAxes, 'reset');

                    % Default shaded area drawn/data & model
                    default(app);
                    createFit(app);
                    plotModel(app);
                    plotData(app);

                end

                %% Cell array to keep track of every ROI point position
                xPointsROI = [];

                %% Implement drawpoint for either side of the area
                if ~isnan(xPoints(1,1)) && ~isnan(xPoints(1,2))
                    X1 = images.roi.Line(app.UIAxes, "Position", [xPoints(1,1) -11; xPoints(1,1) 1], "Deletable",false,"InteractionsAllowed","translate");
                    X2 = images.roi.Line(app.UIAxes, "Position", [xPoints(1,2) -11; xPoints(1,2) 1], "Deletable",false,"InteractionsAllowed","translate");

                    %% Add callbacks for when point moves
                    addlistener(X1, "MovingROI", @(src,event)shader(app,src,event));
                    addlistener(X2, "MovingROI", @(src,event)shader(app,src,event));

                    %% Store points in vector
                    xPointsROI{1,1} = X1;
                    xPointsROI{1,2} = X2;
                end

                createFit(app);
                save_to_base(1);
            % If ROI area is changed through manual mouse input
            else

                ShadeArea{1,1} = [ ];
                ShadeArea{1,1}=[xPointsROI{1,1}.Position(1),xPointsROI{1,2}.Position(1)];


                % Normal default(app) with Shade deletions in the middle
                hold(app.UIAxes, 'on');
                RGarea = ShadeArea{1,1};
                AreaLeft = [xMin RGarea(1)];
                AreaRight = [RGarea(2) xMax];

                delete(Shade{1});
                delete(Shade{2});

                Shade{1} = area(app.UIAxes, AreaLeft, [-10 -10]);
                Shade{2} = area(app.UIAxes, AreaRight, [-10 -10]);
                set(Shade{1},'FaceColor',[0.5 0.5 0.5])
                set(Shade{2},'FaceColor',[0.5 0.5 0.5])
                hold(app.UIAxes, 'off');

                xPoints(1,1) = Shade{1}.XData(2);
                xPoints(1,2) = Shade{2}.XData(1);
                Shade{1}.BaseValue = app.UIAxes.YLim(2);


                % We just need shade on top/no need to redraw scatter data
                app.UIAxes.Children = flip(app.UIAxes.Children);

                % This function pauses the rest of the data filtering until
                % mouse release
                % uiwait(app.UIFigure);
                createFit(app);
                plotModel(app);
                save_to_base(1);

            end
        end

        function default(app)
            global xPoints
            global Shade ShadeArea
            global LogLogTime xMin xMax 

            xMin = min(LogLogTime);
            xMax = max(LogLogTime);
            
            %% Draw both shaded areas up until XLims
            hold(app.UIAxes, 'on');
            RGarea = ShadeArea{1,1};
            AreaLeft = [xMin RGarea(1)];
            AreaRight = [RGarea(2) xMax];

            Shade{1} = area(app.UIAxes, AreaLeft, [-10 -10]);
            Shade{2} = area(app.UIAxes, AreaRight, [-10 -10]);
            set(Shade{1},'FaceColor',[0.5 0.5 0.5])
            set(Shade{2},'FaceColor',[0.5 0.5 0.5])
            hold(app.UIAxes, 'off');

            %% Store Lower and Upper X coordinates
            xPoints(1,1) = Shade{1}.XData(2);
            xPoints(1,2) = Shade{2}.XData(1);

            % Do I change these base values for Log Spacing?
            Shade{1,1}.BaseValue = app.UIAxes.YLim(2);
            save_to_base(1);
        end


        function plotModel(app)
            global Curve xData yfitData xPoints

            % Plot the model and its limits
            delete(Curve);

            var = app.UIAxes.YLim;
            hold(app.UIAxes, 'on' );
            Curve = plot(app.UIAxes, xData, yfitData, 'color', [0.9290 0.6940 0.1250]);
            hold (app.UIAxes, 'off');
            app.UIAxes.YLim = var;

            app.MinX.Value = xPoints(1,1);
            app.MaxX.Value = xPoints(1,2);
            save_to_base(1);
        end

        function plotData(app)
            global Data  LogLogTime LogLogABS

            % Plot the data
            delete(Data);
            hold(app.UIAxes, 'on' );
            Data = scatter(app.UIAxes, LogLogTime, LogLogABS, 10, [0 0.4470 0.7410], 'filled');
            hold (app.UIAxes, 'off');
            save_to_base(1);
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, SolarSpecGUI, LinData, LogData, Time, LogTimeArray, KinInd, plot_wavelengths)
            global  IndexSize LogLogABS LogLogTime LogLinABS LogLinTime KinInd
            global  Time LogTimeArray
            
            % Store main app object and other input arguments
            app.CallingApp = SolarSpecGUI;
            
            %Proper startup to keep app looking clean at first
            app.InvertABSSwitch.Value = 'Off';
            
            %Re-apply the app components/labels
            app.UIAxes.Visible = 1;
            app.FitEqnTextArea.Visible = 1;
            app.WavelengthText.Visible = 1;
            app.MinX.Visible = 1;
            app.MaxX.Visible = 1;
            app.FitEqnTextAreaLabel.Visible = 1;
            app.xLabel.Visible = 1;
            app.xLabel_2.Visible = 1;
            app.InvertABSSwitch.Visible = 1;
            app.InvertABSLabel.Visible = 1;
            
            %Index variables for which files the GUI is viewing
            IndexSize = width(LogData);
            app.Index = KinInd;
            KinInd = KinInd + 1;
            
            % Get proper input values for second app
            % Log of the Log-Spaced Linear Data
            LogLogABS = real(log10(LogData{:,KinInd}(LogTimeArray>0)));
            LogLogTime = real(log10(LogData{:,1}(LogTimeArray>0)));

            % Log of the Lin-Spaced Linear Data
            LogLinABS = real(log10(LinData{:,KinInd}(Time>0)));
            LogLinTime = real(log10(LinData{:,1}(Time>0)));

            shader(app);

            % Add labels separately to view title, xlabel, and ylabel
            title(app.UIAxes, 'Log-Log TAS Data Analysis');
            ylabel(app.UIAxes,'LogABS');
            xlabel(app.UIAxes,'LogTime');
        end

        % Button pushed function: PrevButton
        function PrevButtonPushed(app, event)
            global KinInd LogData LogTimeArray LogLogABS LogLogTime
            app.InvertABSSwitch.Value = 'Off';
            KinInd = KinInd - 1;
            if KinInd < 2
                KinInd = 2;
            end

            LogLogABS = real(log10(LogData{:,KinInd}(LogTimeArray>0)));
            LogLogTime = log10(LogData{:,1}(LogTimeArray>0));

            shader(app);

            % Add labels separately to view title, xlabel, and ylabel
            title(app.UIAxes, 'Log-Log TAS Data Analysis');
            ylabel(app.UIAxes,'LogABS');
            xlabel(app.UIAxes,'LogTime');
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            global KinInd IndexSize LogData LogTimeArray LogLogABS LogLogTime
            app.InvertABSSwitch.Value = 'Off';
            KinInd = KinInd + 1;
            if KinInd > IndexSize
                KinInd = IndexSize;
            end

            LogLogABS = real(log10(LogData{:,KinInd}(LogTimeArray>0)));
            LogLogTime = log10(LogData{:,1}(LogTimeArray>0));

            shader(app);

            % Add labels separately to view title, xlabel, and ylabel
            title(app.UIAxes, 'Log-Log TAS Data Analysis');
            ylabel(app.UIAxes,'LogABS');
            xlabel(app.UIAxes,'LogTime');
        end

        % Value changed function: MinX
        function MinXValueChanged(app, event)
            global xPoints LogLogTime X1 X2 Shade ShadeArea
            
            latestvalue = event.Value;
            
            %Keep the changed input within the boundary conditons
            if (latestvalue < min(LogLogTime))
                latestvalue = min(LogLogTime); %#ok<NASGU> 
            elseif (latestvalue > X2.Position(1))
                latestvalue = X2.Position(1) + 1; %#ok<NASGU> 
            else
                xPoints(1,1) = latestvalue;

                [~, line_index] = min(abs(LogLogTime - latestvalue));
                closest = LogLogTime(line_index);

                X1.Position = [closest -11; closest 1];

                delete(Shade{1});
                delete(Shade{2});

                ShadeArea{1,1} = [closest xPoints(1,2)];

                default(app);

                % We just need shaded area on top/no need to redraw scatter
                app.UIAxes.Children = flip(app.UIAxes.Children);
                createFit(app);
                plotModel(app);
            end
        end

        % Value changed function: MaxX
        function MaxXValueChanged(app, event)
            global xPoints LogLogTime X2 X1 Shade ShadeArea

            latestvalue = event.Value;
            if (latestvalue > max(LogLogTime))
                latestvalue = max(LogLogTime); %#ok<NASGU> 

            elseif (latestvalue < X1.Position(1))
                latestvalue = X1.Position(1) - 1; %#ok<NASGU> 
            else
                xPoints(1,2) = latestvalue;

                [~, line_index] = min(abs(LogLogTime - latestvalue));
                closest = LogLogTime(line_index);

                X2.Position = [closest -11; closest 1];

                delete(Shade{1});
                delete(Shade{2});

                ShadeArea{1,1} = [xPoints(1,1) closest];

                default(app);

                % We just need shaded area on top/no need to redraw scatter
                app.UIAxes.Children = flip(app.UIAxes.Children);
                createFit(app);
                plotModel(app);
            end
        end

        % Value changed function: InvertABSSwitch
        function InvertABSSwitchValueChanged(app, event)
            global LogData KinInd LogLogABS LogLogTime LogTimeArray 

            % Check the event value and proceed accordingly if inverted
            value= convertCharsToStrings(event.Value);
            if (value == 'On') %#ok<BDSCA> 

                inverseLog = (LogData{:,KinInd}).*(-1);
                LogLogABS = real(log10(inverseLog(LogTimeArray<0)));

                %%LogLogTime is a 96x1 complex double after inverse?
                LogLogTime = real(log10(LogData{:,1}(LogTimeArray<0)));

                createFit(app);
                plotModel(app);
                plotData(app);

            else

                LogLogABS = real(log10(LogData{:,KinInd}(LogTimeArray>0)));
                LogLogTime = log10(LogData{:,1}(LogTimeArray>0));

                createFit(app);
                plotModel(app);
                plotData(app);

            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            global LogLogTime LogLogABS yfitData xPoints fitresult FolderPath

            % Store necessary variables for export
            time = LogLogTime;
            abs = LogLogABS;
            yfit = yfitData;
            fitwindow = [xPoints(1,1); xPoints(1,2)];
            fiteqn = [fitresult.p1; fitresult.p2];

            % Create blank array with titles
            p = {'LogTime', 'LogABS', 'yfitData', 'fitWindow', 'fitEquation'};
            A = nan(size(time,1),5);
            A(1:length(time),1) = time;
            A(1:length(abs),2) = abs;
            A(1:length(yfit),3) = yfit;
            A(1:length(fitwindow),4) = fitwindow;
            A(1:length(fiteqn),5) = fiteqn;

            % Convert 5 column vectors into table with appropriate names
            T = array2table(A);
            T.Properties.VariableNames(1:5) = p;

            % Add wavelength into title of filename
            f = sprintf('TASLogData%s.csv',app.WavelengthText.Text);
            place = fullfile(FolderPath,f);
            writetable(T,place);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            global KinInd
            
            % Reset index to same value as when you opened from SpecBuilder
            KinInd = app.Index;
            
            % Re-enable the figure button and then delete the app
            app.CallingApp.LogLogFigureButton.Enable = 'on';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Log-Log TAS Data Analysis')
            xlabel(app.UIAxes, 'LogTime')
            ylabel(app.UIAxes, 'LogABS')
            zlabel(app.UIAxes, 'Z')
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PrevButton = uibutton(app.UIFigure, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PrevButton.Text = 'Prev';
            app.MinX = uieditfield(app.UIFigure, 'numeric');
            app.MinX.ValueChangedFcn = createCallbackFcn(app, @MinXValueChanged, true);
            app.xLabel_2 = uilabel(app.UIFigure);
            app.xLabel_2.Text = 'x <';
            app.MaxX = uieditfield(app.UIFigure, 'numeric');
            app.MaxX.ValueChangedFcn = createCallbackFcn(app, @MaxXValueChanged, true);
            app.xLabel = uilabel(app.UIFigure);
            app.xLabel.Text = 'x >';
            app.WavelengthText = uilabel(app.UIFigure);
            app.WavelengthText.Text = 'Displaying _ nm';
            app.FitEqnTextAreaLabel = uilabel(app.UIFigure);
            app.FitEqnTextAreaLabel.Text = 'Fit Eq''n:';
            app.FitEqnTextArea = uitextarea(app.UIFigure);
            app.InvertABSLabel = uilabel(app.UIFigure);
            app.InvertABSLabel.Text = {'Invert '; 'ABS'};
            app.InvertABSSwitch = uiswitch(app.UIFigure, 'toggle');
            app.InvertABSSwitch.ValueChangedFcn = createCallbackFcn(app, @InvertABSSwitchValueChanged, true);
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LogLogApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end